classdef Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        GeneralTab                   matlab.ui.container.Tab
        LeadDBSVersionDropDown       matlab.ui.control.DropDown
        LeadDBSVersionDropDownLabel  matlab.ui.control.Label
        FEMTab                       matlab.ui.container.Tab
        EncapsulationEditField       matlab.ui.control.NumericEditField
        EncapsulationEditFieldLabel  matlab.ui.control.Label
        CSFEditField                 matlab.ui.control.NumericEditField
        CSFEditFieldLabel            matlab.ui.control.Label
        WhitematterEditField         matlab.ui.control.NumericEditField
        WhitematterEditFieldLabel    matlab.ui.control.Label
        ConductivityvaluesSmLabel    matlab.ui.control.Label
        GraymatterEditField          matlab.ui.control.NumericEditField
        GraymatterEditFieldLabel     matlab.ui.control.Label
        mmLabel_2                    matlab.ui.control.Label
        EncapsulationlayerthicknessEditField  matlab.ui.control.NumericEditField
        EncapsulationlayerthicknessEditFieldLabel  matlab.ui.control.Label
        OptimizationTab              matlab.ui.container.Tab
        VoxelFilterSizeSlider        matlab.ui.control.Slider
        VoxelFilterSizeSliderLabel   matlab.ui.control.Label
        DownsamplepointsofinterestCheckBox  matlab.ui.control.CheckBox
        mmLabel                      matlab.ui.control.Label
        ApplyButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        TuneStim % Description
        updatedSettings
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, TuneStim, simulationSettings)
            app.updatedSettings = simulationSettings;
            % Store main app in property
            app.TuneStim = TuneStim;
        end

        % Value changed function: LeadDBSVersionDropDown
        function LeadDBSVersionDropDownValueChanged(app, event)
            app.updatedSettings.LeadDBSVersion = app.LeadDBSVersionDropDown.Value;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            updateSettings(app.TuneStim,app.updatedSettings)
            app.TuneStim.SettingsMenu.Enable = 'on';
            app.delete;
        end

        % Value changed function: VoxelFilterSizeSlider
        function VoxelFilterSizeSliderValueChanged(app, event)
            app.updatedSettings.VoxelFilterSize = app.VoxelFilterSizeSlider.Value*1e-3;
        end

        % Value changed function: DownsamplepointsofinterestCheckBox
        function DownsamplepointsofinterestCheckBoxValueChanged(app, event)
            app.updatedSettings.downsample = app.DownsamplepointsofinterestCheckBox.Value;
        end

        % Value changed function: EncapsulationlayerthicknessEditField
        function EncapsulationlayerthicknessEditFieldValueChanged(app, event)
            app.updatedSettings.encapsulationThickness = app.EncapsulationlayerthicknessEditField.Value*1e-3;
        end

        % Value changed function: GraymatterEditField
        function GraymatterEditFieldValueChanged(app, event)
            app.updatedSettings.conductivities.GM = app.GraymatterEditField.Value;
        end

        % Value changed function: WhitematterEditField
        function WhitematterEditFieldValueChanged(app, event)
            app.updatedSettings.conductivities.WM = app.WhitematterEditField.Value;
            
        end

        % Value changed function: CSFEditField
        function CSFEditFieldValueChanged(app, event)
            app.updatedSettings.conductivities.CSF = app.CSFEditField.Value;
        end

        % Value changed function: EncapsulationEditField
        function EncapsulationEditFieldValueChanged(app, event)
            app.updatedSettings.conductivities.Encapsulation = app.EncapsulationEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.LeadDBSVersionDropDownLabel = uilabel(app.GeneralTab);
            app.LeadDBSVersionDropDownLabel.Text = 'Lead-DBS Version';
            app.LeadDBSVersionDropDown = uidropdown(app.GeneralTab);
            app.LeadDBSVersionDropDown.Items = {'2.7', '3.2'};
            app.LeadDBSVersionDropDown.ValueChangedFcn = createCallbackFcn(app, @LeadDBSVersionDropDownValueChanged, true);
            app.LeadDBSVersionDropDown.Value = '3.2';
            app.FEMTab = uitab(app.TabGroup);
            app.FEMTab.Title = 'FEM';
            app.EncapsulationlayerthicknessEditFieldLabel = uilabel(app.FEMTab);
            app.EncapsulationlayerthicknessEditFieldLabel.Text = 'Encapsulation layer thickness';
            app.EncapsulationlayerthicknessEditField = uieditfield(app.FEMTab, 'numeric');
            app.EncapsulationlayerthicknessEditField.ValueChangedFcn = createCallbackFcn(app, @EncapsulationlayerthicknessEditFieldValueChanged, true);
            app.EncapsulationlayerthicknessEditField.Value = 0.1;
            app.mmLabel_2 = uilabel(app.FEMTab);
            app.mmLabel_2.Text = '[mm]';
            app.GraymatterEditFieldLabel = uilabel(app.FEMTab);
            app.GraymatterEditFieldLabel.Text = 'Gray matter';
            app.GraymatterEditField = uieditfield(app.FEMTab, 'numeric');
            app.GraymatterEditField.Limits = [0 Inf];
            app.GraymatterEditField.ValueChangedFcn = createCallbackFcn(app, @GraymatterEditFieldValueChanged, true);
            app.GraymatterEditField.Value = 0.09;
            app.ConductivityvaluesSmLabel = uilabel(app.FEMTab);
            app.ConductivityvaluesSmLabel.Text = 'Conductivity values [S/m]';
            app.WhitematterEditFieldLabel = uilabel(app.FEMTab);
            app.WhitematterEditFieldLabel.Text = 'White matter';
            app.WhitematterEditField = uieditfield(app.FEMTab, 'numeric');
            app.WhitematterEditField.Limits = [0 Inf];
            app.WhitematterEditField.ValueChangedFcn = createCallbackFcn(app, @WhitematterEditFieldValueChanged, true);
            app.WhitematterEditField.Value = 0.06;
            app.CSFEditFieldLabel = uilabel(app.FEMTab);
            app.CSFEditFieldLabel.Text = 'CSF';
            app.CSFEditField = uieditfield(app.FEMTab, 'numeric');
            app.CSFEditField.Limits = [0 Inf];
            app.CSFEditField.ValueChangedFcn = createCallbackFcn(app, @CSFEditFieldValueChanged, true);
            app.CSFEditField.Value = 2;
            app.EncapsulationEditFieldLabel = uilabel(app.FEMTab);
            app.EncapsulationEditFieldLabel.Text = 'Encapsulation';
            app.EncapsulationEditField = uieditfield(app.FEMTab, 'numeric');
            app.EncapsulationEditField.Limits = [0 Inf];
            app.EncapsulationEditField.ValueChangedFcn = createCallbackFcn(app, @EncapsulationEditFieldValueChanged, true);
            app.EncapsulationEditField.Value = 0.18;
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.mmLabel = uilabel(app.OptimizationTab);
            app.mmLabel.Text = '[mm]';
            app.DownsamplepointsofinterestCheckBox = uicheckbox(app.OptimizationTab);
            app.DownsamplepointsofinterestCheckBox.ValueChangedFcn = createCallbackFcn(app, @DownsamplepointsofinterestCheckBoxValueChanged, true);
            app.DownsamplepointsofinterestCheckBox.Text = 'Downsample points of interest';
            app.DownsamplepointsofinterestCheckBox.Value = true;
            app.VoxelFilterSizeSliderLabel = uilabel(app.OptimizationTab);
            app.VoxelFilterSizeSliderLabel.Text = 'Voxel Filter Size';
            app.VoxelFilterSizeSlider = uislider(app.OptimizationTab);
            app.VoxelFilterSizeSlider.Limits = [0.8 1.1];
            app.VoxelFilterSizeSlider.MajorTicks = [0.8 0.85 0.9 0.95 1 1.05 1.1];
            app.VoxelFilterSizeSlider.ValueChangedFcn = createCallbackFcn(app, @VoxelFilterSizeSliderValueChanged, true);
            app.VoxelFilterSizeSlider.Value = 0.95;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end