classdef TuneStim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TuneS                           matlab.ui.Figure
        HomeMenu                        matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        TextArea                        matlab.ui.control.TextArea
        RunButton                       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PatientsTab                     matlab.ui.container.Tab
        Switch                          matlab.ui.control.Switch
        ConfirmselectionButton          matlab.ui.control.Button
        UITableSubfolders               matlab.ui.control.Table
        LoadSubfoldersButton            matlab.ui.control.Button
        UITablePatients                 matlab.ui.control.Table
        OrientationLabel                matlab.ui.control.Label
        UITableLeads                    matlab.ui.control.Table
        UITableOrientation              matlab.ui.control.Table
        OptimizationTab                 matlab.ui.container.Tab
        PlotOption                      matlab.ui.control.CheckBox
        PlotResultsLabel                matlab.ui.control.Label
        Omega                           matlab.ui.control.EditField
        RankingcoefficientsLabel        matlab.ui.control.Label
        omega_tomega_comega_sLabel      matlab.ui.control.Label
        ConstraintthresholdVmEditField  matlab.ui.control.NumericEditField
        ConstraintthresholdVmEditFieldLabel  matlab.ui.control.Label
        Rebuild                         matlab.ui.control.CheckBox
        RebuildLabel                    matlab.ui.control.Label
        VTAthresholdVmEditField         matlab.ui.control.NumericEditField
        VTAthresholdVmEditFieldLabel    matlab.ui.control.Label
        ParallelProcessesEditField      matlab.ui.control.NumericEditField
        ParallelProcessesEditFieldLabel  matlab.ui.control.Label
        OptimizationschemeDropDown      matlab.ui.control.DropDown
        OptimizationschemeDropDownLabel  matlab.ui.control.Label
        CheckBoxOptimize                matlab.ui.control.CheckBox
        RunOptimizationLabel            matlab.ui.control.Label
        Space                           matlab.ui.control.Switch
        SpaceLabel                      matlab.ui.control.Label
        AtlasDropDown                   matlab.ui.control.DropDown
        AtlasDropDownLabel              matlab.ui.control.Label
        TargetButton                    matlab.ui.control.Button
        TargetListBox                   matlab.ui.control.ListBox
        ConstraintListBox               matlab.ui.control.ListBox
        ConstraintButton                matlab.ui.control.Button
        CompareSettingsTab              matlab.ui.container.Tab
        DeleteRowButtonRight            matlab.ui.control.Button
        DeleteRowButtonLeft             matlab.ui.control.Button
        InsertRowButtonRight            matlab.ui.control.Button
        InsertRowButtonLeft             matlab.ui.control.Button
        RightHemisphereLabel            matlab.ui.control.Label
        UITableSettingsRight            matlab.ui.control.Table
        LeftHemisphereLabel             matlab.ui.control.Label
        UITableSettingsLeft             matlab.ui.control.Table
        CheckBoxCompareSettings         matlab.ui.control.CheckBox
        CompareSettingsLabel            matlab.ui.control.Label
        TargetCovCheckBox               matlab.ui.control.CheckBox
        TargetCoverageLabel             matlab.ui.control.Label
        DiceCoefficientsLabel           matlab.ui.control.Label
        DiceCheckBox                    matlab.ui.control.CheckBox
        SelectPatientListBox            matlab.ui.control.ListBox
        SelectPatientListBoxLabel       matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        CohortPathEditField             matlab.ui.control.EditField
        CohortPathEditFieldLabel        matlab.ui.control.Label
        DBSTuneSLabel                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        gui = addpath('./GUI')
        mni = addpath('./MNI')
        leads = addpath('./Leads')
        comsol = addpath('./Comsol')
        selectedPath = 'C:' 
        selectedPatients = []
        selectedOrientation = [];
        SettingsLeft = struct('dummy',[]);
        SettingsRight = struct('dummy',[]);
        simulationSettings
        atlasAtlasDropDownItems

         % Description
    end
    
    methods (Access = private)
        
        function initTables(app)
            Npatients = size(app.selectedPatients,1);
            app.UITableLeads.Data = repmat({'Abbott Infinity Directed (short)'},Npatients,1); 
            %app.UITableLeads.ColumnFormat = {{'S:t Jude 1331', 'Boston Scientific 2202', 'Boston Scientific Vercise Cartesia'}}; % have option cell array within ColumnFormat property to indicate the 1st column is using dropdown menus.
            app.UITableLeads.ColumnEditable = true;

            app.UITableOrientation.ColumnEditable = true;
            app.UITableOrientation.Data = repmat({0 0},Npatients,1); 
            
        end
    end
    
    methods (Access = public)
        
        function updateSettings(app,updatedSettings)
            app.simulationSettings = updatedSettings;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITableLeads.Data = {'Abbott Infinity Directed (short)'; 'Boston Scientific Vercise Directional 2202'; 'Boston Scientific Vercise Standard 2201';'Medtronic 3887'};%repmat({'S:t Jude 1331'},Npatients,1); 
            app.UITableLeads.ColumnFormat = {{'Abbott Infinity Directed (short)', 'Boston Scientific Vercise Directional 2202', 'Boston Scientific Vercise Standard 2201','Medtronic 3887'}}; % have option cell array within ColumnFormat property to indicate the 1st column is using dropdown menus.
            app.UITableLeads.ColumnEditable = true;
            app.simulationSettings.LeadDBSVersion = 3.2;
            app.simulationSettings.VoxelFilterSize = 0.95*1e-3;
            app.simulationSettings.downsample = 1;
            app.simulationSettings.encapsulationThickness = 0.1*1e-3;
            app.simulationSettings.conductivities.GM = 0.09;
            app.simulationSettings.conductivities.WM = 0.06;
            app.simulationSettings.conductivities.CSF = 2.0;
            app.simulationSettings.conductivities.Encapsulation = 0.18;

            % Update available atlases for drop down
            items = dir([ea_getearoot,'templates',filesep,'space',filesep,'MNI152NLin2009bAsym',filesep,'atlases']);
            dirFlags = [items.isdir];
            folderNames = {items(dirFlags).name};
            folderNames = folderNames(~ismember(folderNames, {'.', '..'}));
            app.AtlasDropDown.Items = folderNames;
        end

        % Callback function: BrowseButton, CohortPathEditField
        function BrowseButtonPushed(app, event)
            app.selectedPath = uigetdir();
            app.CohortPathEditField.Value = app.selectedPath;
        end

        % Button pushed function: LoadSubfoldersButton
        function LoadSubfoldersButtonPushed(app, event)
            % Open folder selection dialog
            folderPath = app.selectedPath;
            if folderPath == 0
                return; % User cancelled the folder selection
            end
            if app.simulationSettings.LeadDBSVersion < 3.0
             % Get list of sub-folders
                subFolders = dir(folderPath);
                %subFolderNames = {subFolders([subFolders.isdir] & ~ismember({subFolders.name}, {'.', '..'})).name};
            elseif app.simulationSettings.LeadDBSVersion >= 3.0
                subFolders = dir(append(folderPath,filesep,'derivatives',...
                                 filesep, 'leaddbs',filesep));
            end
            subFolderNames = {subFolders([subFolders.isdir] & ~ismember({subFolders.name}, {'.', '..'})).name};
            % Create data for the table
            tableData = cell(length(subFolderNames), 1);
            for i = 1:length(subFolderNames)
                tableData{i, 1} = subFolderNames{i};
            end
            
            % Set table data
            app.UITableSubfolders.Data = tableData;
        end

        % Button pushed function: ConfirmselectionButton
        function ConfirmselectionButtonPushed(app, event)
            allSubfolders = app.UITableSubfolders.Data;
            selectedSubfolders = app.selectedPatients;
            
            % Create a table with the selected subfolder names
            data = cell(size(selectedSubfolders,1), 1);
            for i = 1:size(selectedSubfolders,1)
                idx = selectedSubfolders(i);
                data{i, 1} = allSubfolders{idx};
            end

            % Update the table in the app
            app.UITablePatients.Data = data;
            app.SelectPatientListBox.Items = data;

            % Fill table for lead selection
            initTables(app)
        end

        % Cell selection callback: UITableSubfolders
        function UITableSubfoldersCellSelection(app, event)
            
            app.selectedPatients = event.Indices;            
        end

        % Button pushed function: TargetButton
        function TargetButtonPushed(app, event)
            if app.TargetListBox.Visible == 1
                set(app.TargetListBox,'Visible',0);
            else
                set(app.TargetListBox,'Visible',1);
            end
        end

        % Button pushed function: ConstraintButton
        function ConstraintButtonPushed(app, event)
            if app.ConstraintListBox.Visible == 1
                set(app.ConstraintListBox,'Visible',0);
            else
                set(app.ConstraintListBox,'Visible',1);
            end
        end

        % Value changed function: AtlasDropDown
        function AtlasDropDownValueChanged(app, event)
            value = app.AtlasDropDown.Value;
            atlasPath = [ea_getearoot,'templates',filesep,'space',filesep,'MNI152NLin2009bAsym',filesep,'atlases',filesep,value,filesep,'lh'];

        if ~isfolder(atlasPath)
            error('Atlas folder must be a valid folder path.');
        end

        % List all items in the folder
        allItems = dir(atlasPath);
        itemsdata = {allItems([allItems.isdir] == 0).name};
        [~, items] = cellfun(@(f) fileparts(f), itemsdata, 'UniformOutput', false);
        items = cellfun(@(name) ...
                regexprep(name, '\.nii$', '', 'ignorecase'), ...
                items, 'UniformOutput', false);

            % if strcmp(value, 'DISTAL Minimal (Ewert 2017)')
            %     items = {'STN motor','STN associative','STN limbic','GPi premotor','GPi primarymotor','GPi sensorimotor','GPi sensory','GPi temporal'};
            %     itemsdata = {'STN_motor.nii.gz','STN_associative.nii.gz','STN_limbic.nii.gz','GPi_premotor.nii.gz','GPi_primarymotor.nii.gz','GPi_sensorimotor.nii.gz','GPi_sensory.nii.gz','GPi_temporal.nii.gz'};
            % 
            % elseif strcmp(value,'STN Sweetspots (Dembek 2019)')
            %     items = {'Any Side Effect MeanMapGradient','Overall Motor Improvement MeanMapGradient','STN associative ','STN limbic'};
            %     itemsdata = {'Any_Side_Effect_MeanMapGradient.nii.gz','Overall_Motor_Improvement_MeanMapGradient.nii.gz','STN_associative.nii.gz','STN_limbic.nii.gz'};
            % 
            % elseif strcmp(value,'STN-Subdivisions (Accolla 2014)')
            %     items = {'STN motor','STN associative','STN limbic'};
            %     itemsdata = {'STN_motor.nii','STN_associative.nii','STN_limbic.nii'};
            % 
            % elseif strcmp(value,'DBS Tractography Atlas (Middlebrooks 2020)')
            %     items= {'STN limbic', 'STN limbic tract', 'STN associative','STN associative tract','STN motor','STN motor tract'};
            %     itemsdata = {'STN_limbic.nii.gz', 'STN_limbic_tract.mat', 'STN_associative.nii.gz','STN_associative_tract.mat','STN_motor.nii.gz','STN_motor_tract.mat'};
            % 
            % elseif strcmp(value,'Human Dysfunctome Atlas (Hollunder 2024)')
            %     items= {'PD Sweet Spot', 'DYT Sweet Spot', 'OCD Sweet Spot','TS Sweet Spot','STN associative','STN limbic','None'};
            %     itemsdata = {'Sweet_Spot_PD.nii.gz', 'Sweet_Spot_DYT.nii.gz', 'Sweet_Spot_OCD.nii.gz','Sweet_Spot_TS.nii.gz','STN_associative.nii.gz','STN_limbic.nii.gz',''};
            % end


            set(app.ConstraintListBox,'Items',items);
            set(app.TargetListBox,'Items',items);

            set(app.ConstraintListBox,'ItemsData',itemsdata);
            set(app.TargetListBox,'ItemsData',itemsdata);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            cohort.simulationSettings = app.simulationSettings;
            cohort.folder =  app.selectedPath;
            cohort.patNames = app.UITablePatients.Data;
            cohort.leads = app.UITableLeads.Data;
            cohort.atlas = app.AtlasDropDown.Value;
            cohort.targets = app.TargetListBox.Value;
            cohort.constraints = app.ConstraintListBox.Value;
            cohort.rebuild = app.Rebuild.Value;
            cohort.plotoption = app.PlotOption.Value;
            cohort.omega = app.Omega.Value;
            cohort.compareSettings = app.CheckBoxCompareSettings.Value;
            cohort.computeDice = app.DiceCheckBox.Value;
            cohort.computeTargetCoverage = app.TargetCovCheckBox.Value;
            cohort.optimize = app.CheckBoxOptimize.Value;

            cohort.selectedPatient = app.SelectPatientListBox.Value;
            %ClinicalSettings = {app.UITableSettingsLeft.Data,...
            %                    app.UITableSettingsRight.Data};
            cohort.ClinicalSettings = {app.SettingsLeft,...
                                app.SettingsRight};


            %batch = app.batchCheckBox.Value;
            cohort.space = app.Space.Value;
            cohort.threads = app.ParallelProcessesEditField.Value;
            cohort.optischeme = app.OptimizationschemeDropDown.Value;
            cohort.EThreshold = app.VTAthresholdVmEditField.Value;
            cohort.CThreshold = app.ConstraintthresholdVmEditField.Value;
            %SafetyMargin = app.RelaxationTargetCoverageEditField.Value;
            if strcmp(app.Switch.Value,'Current')
                cohort.unit = '1mA';
            elseif strcmp(app.Switch.Value,'Voltage')
                cohort.unit = '1V';
            else 
                disp('Specify valid unit')
            end

            cohort.leadOrientations = app.UITableOrientation.Data;
            % if left == 1 && right == 0
            %     hemisphere  = {'sin'};
            %     lead_orientation = [app.LeftEditField.Value];
            % elseif right == 1 && left == 0
            %     hemisphere = {'dx'};
            %     lead_orientation = [app.RightEditField.Value];
            % elseif right==1 && left==1
            %     hemisphere = {"dx","sin"};
            %     lead_orientation = [app.LeftEditField.Value,app.RightEditField.Value];
            % end

            if strcmp(cohort.optischeme,'Linear')
                cohort.optischeme = 'linear';
            elseif strcmp(cohort.optischeme,'Minimum Coverage')
                cohort.optischeme = 'mincov';
            end

            
            if strcmp(cohort.folder,'C:')
                app.TextArea.Value = 'Please pick patient directory';

            %elseif ~exist('hemisphere','var')==1
            %    app.TextArea.Value = 'Please pick hemisphere and insert lead orientation';
             
            % elseif batch
            %     cohortfolder = strcat("'", cohortfolder,"'");
            %     atlas = strcat("'", atlas,"'");
            %     space = strcat("'", space,"'");
            %     lead = strcat("'",lead,"'");
            %     optischeme = strcat("'",optischeme,"'");
            % 
            %     hemisphere = cell_to_string(hemisphere);
            %     lead_orientation = append('[',cell_to_string(lead_orientation),']');
            %     targets = cell_to_string(targets);
            %     constraints = cell_to_string(constraints);
            % 
            %     cmd = sprintf('sbatch Pipeline.sh %s %s %s %s %s %s %s %s %s %s %s %s',cohortfolder,hemisphere,lead,lead_orientation,atlas,targets,constraints,optischeme,string(EThreshold),string(SafetyMargin),string(threads),space,string(rebui));
            %     app.TextArea.Value = cmd;
            %     pause(1)
            %     [nr,msg]=system(cmd);
            %     app.TextArea.Value = msg;
                
            else
                set(app.TuneS, 'Pointer','watch')
                set(app.RunButton,'Text',{'Running...';''})
                set(app.TextArea,'Value', '')
                pause(0.1)
                msg = main(cohort);
                app.TextArea.Value = msg;
                app.TuneS.Pointer = 'arrow';
                app.RunButton.Text = {'Run'; ''};
            end
    
            function str = cell_to_string(cellstring)
            str = '';
            for i = 1:length(cellstring)
                try
                    content = cellstring{i};
                catch
                    content = string(cellstring(i));
                end
                str = append(str,content,',');
            end
            str = strcat("'",str,"'");
            end
        end

        % Value changed function: SelectPatientListBox
        function SelectPatientListBoxValueChanged(app, event)
            name =  app.SelectPatientListBox.Value;
            %clinicalSettingsAll.name.Left = {'C2A,C2B,C2C',2;'C3A',3};
            %clinicalSettingsAll.name.Right =  {'C4X',1.5};
            if isfield(app.SettingsLeft, name)
                app.UITableSettingsLeft.Data = app.SettingsLeft.(name); %{'C2A,C2B,C2C',2;'C3A',3};
            else
                app.UITableSettingsLeft.Data = {'C2A,C2B,C2C',2,60};

            end
            if isfield(app.SettingsRight, name)
                app.UITableSettingsRight.Data = app.SettingsRight.(name);
            else
                app.UITableSettingsRight.Data = {'C4X',1.5,60};
            end

        end

        % Button pushed function: InsertRowButtonLeft
        function InsertRowButtonLeftPushed(app, event)
            app.UITableSettingsLeft.Data(end+1,:) = {'',0,60};
        end

        % Button pushed function: InsertRowButtonRight
        function InsertRowButtonRightPushed(app, event)
            app.UITableSettingsRight.Data(end+1,:) = {'',0,60};
        end

        % Cell edit callback: UITableSettingsLeft
        function UITableSettingsLeftCellEdit(app, event)
            newData = app.UITableSettingsLeft.Data;
            name = app.SelectPatientListBox.Value;
            app.SettingsLeft.(name) = newData;
            app.UITableSettingsLeft.Data = app.SettingsLeft.(name);
        end

        % Cell edit callback: UITableSettingsRight
        function UITableSettingsRightCellEdit(app, event)
            newData = app.UITableSettingsRight.Data;
            name = app.SelectPatientListBox.Value;
            app.SettingsRight.(name) = newData;
            app.UITableSettingsRight.Data = app.SettingsRight.(name);
        end

        % Button pushed function: DeleteRowButtonLeft
        function DeleteRowButtonLeftPushed(app, event)
            app.UITableSettingsLeft.Data(end,:) = [];
        end

        % Button pushed function: DeleteRowButtonRight
        function DeleteRowButtonRightPushed(app, event)
            app.UITableSettingsRight.Data(end,:) = [];
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            Settings(app,app.simulationSettings)
            app.SettingsMenu.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TuneS = uifigure('Visible', 'off');
            app.TuneS.Name = 'TuneS';
            app.HomeMenu = uimenu(app.TuneS);
            app.HomeMenu.Text = 'Home';
            app.SettingsMenu = uimenu(app.HomeMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.Image = uiimage(app.TuneS);
            app.Image.ImageSource = 'option1.png';
            app.DBSTuneSLabel = uilabel(app.TuneS);
            app.DBSTuneSLabel.Text = {'DBS TuneS'; ''};
            app.CohortPathEditFieldLabel = uilabel(app.TuneS);
            app.CohortPathEditFieldLabel.Text = 'Cohort Path';
            app.CohortPathEditField = uieditfield(app.TuneS, 'text');
            app.CohortPathEditField.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton = uibutton(app.TuneS, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.TabGroup = uitabgroup(app.TuneS);
            app.PatientsTab = uitab(app.TabGroup);
            app.PatientsTab.Title = 'Patients';
            app.UITableOrientation = uitable(app.PatientsTab);
            app.UITableOrientation.ColumnName = {'Left'; 'Right'};
            app.UITableOrientation.RowName = {};
            app.UITableLeads = uitable(app.PatientsTab);
            app.UITableLeads.ColumnName = {'Lead Type'};
            app.UITableLeads.RowName = {};
            app.UITableLeads.ColumnEditable = true;
            app.OrientationLabel = uilabel(app.PatientsTab);
            app.OrientationLabel.Text = 'Orientation';
            app.UITablePatients = uitable(app.PatientsTab);
            app.UITablePatients.ColumnName = {'Included Patients'};
            app.UITablePatients.RowName = {};
            app.LoadSubfoldersButton = uibutton(app.PatientsTab, 'push');
            app.LoadSubfoldersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSubfoldersButtonPushed, true);
            app.LoadSubfoldersButton.Text = 'Load Subfolders';
            app.UITableSubfolders = uitable(app.PatientsTab);
            app.UITableSubfolders.ColumnName = {'Subfolders'};
            app.UITableSubfolders.RowName = {};
            app.UITableSubfolders.CellSelectionCallback = createCallbackFcn(app, @UITableSubfoldersCellSelection, true);
            app.ConfirmselectionButton = uibutton(app.PatientsTab, 'push');
            app.ConfirmselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmselectionButtonPushed, true);
            app.ConfirmselectionButton.Text = 'Confirm selection';
            app.Switch = uiswitch(app.PatientsTab, 'slider');
            app.Switch.Items = {'Current', 'Voltage'};
            app.Switch.Value = 'Current';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.ConstraintButton = uibutton(app.OptimizationTab, 'push');
            app.ConstraintButton.ButtonPushedFcn = createCallbackFcn(app, @ConstraintButtonPushed, true);
            app.ConstraintButton.Text = 'Constraint';
            app.ConstraintListBox = uilistbox(app.OptimizationTab);
            app.ConstraintListBox.Items = {'STN associative', 'STN motor', 'STN limbic', 'GPi premotor', 'GPi primarymotor', 'GPi sensorimotor', 'GPi sensory', 'GPi temporal'};
            app.ConstraintListBox.ItemsData = {'STN_associative.nii.gz', 'STN_motor.nii.gz', 'STN_limbic.nii.gz', 'GPi_premotor.nii.gz', 'GPi_primarymotor.nii.gz', 'GPi_sensorimotor.nii.gz', 'GPi_sensory.nii.gz', 'GPi_temporal.nii.gz'};
            app.ConstraintListBox.Multiselect = 'on';
            app.ConstraintListBox.Visible = 'off';
            app.ConstraintListBox.Value = {'STN_associative.nii.gz'};
            app.TargetListBox = uilistbox(app.OptimizationTab);
            app.TargetListBox.Items = {'STN associative', 'STN motor', 'STN limbic', 'GPi premotor', 'GPi primarymotor', 'GPi sensorimotor', 'GPi sensory', 'GPi temporal'};
            app.TargetListBox.ItemsData = {'STN_associative.nii.gz', 'STN_motor.nii.gz', 'STN_limbic.nii.gz', 'GPi_premotor.nii.gz', 'GPi_primarymotor.nii.gz', 'GPi_sensorimotor.nii.gz', 'GPi_sensory.nii.gz', 'GPi_temporal.nii.gz'};
            app.TargetListBox.Multiselect = 'on';
            app.TargetListBox.Visible = 'off';
            app.TargetListBox.Value = {'STN_associative.nii.gz'};
            app.TargetButton = uibutton(app.OptimizationTab, 'push');
            app.TargetButton.ButtonPushedFcn = createCallbackFcn(app, @TargetButtonPushed, true);
            app.TargetButton.Text = 'Target';
            app.AtlasDropDownLabel = uilabel(app.OptimizationTab);
            app.AtlasDropDownLabel.Text = 'Atlas';
            app.AtlasDropDown = uidropdown(app.OptimizationTab);
            app.AtlasDropDown.Items = {'DISTAL Minimal (Ewert 2017)', 'STN Sweetspots (Dembek 2019)', 'STN-Subdivisions (Accolla 2014)', 'DBS Tractography Atlas (Middlebrooks 2020)', 'Human Dysfunctome Atlas (Hollunder 2024)'};
            app.AtlasDropDown.ValueChangedFcn = createCallbackFcn(app, @AtlasDropDownValueChanged, true);
            app.AtlasDropDown.Value = 'DISTAL Minimal (Ewert 2017)';
            app.SpaceLabel = uilabel(app.OptimizationTab);
            app.SpaceLabel.Text = 'Space';
            app.Space = uiswitch(app.OptimizationTab, 'slider');
            app.Space.Items = {'MNI', 'Native'};
            app.Space.Value = 'MNI';
            app.RunOptimizationLabel = uilabel(app.OptimizationTab);
            app.RunOptimizationLabel.Text = 'Run Optimization';
            app.CheckBoxOptimize = uicheckbox(app.OptimizationTab);
            app.CheckBoxOptimize.Text = '';
            app.OptimizationschemeDropDownLabel = uilabel(app.OptimizationTab);
            app.OptimizationschemeDropDownLabel.Text = 'Optimization scheme';
            app.OptimizationschemeDropDown = uidropdown(app.OptimizationTab);
            app.OptimizationschemeDropDown.Items = {'MILP', 'LP', 'Nonlinear', 'Linear', 'Minimum Coverage'};
            app.OptimizationschemeDropDown.Value = 'MILP';
            app.ParallelProcessesEditFieldLabel = uilabel(app.OptimizationTab);
            app.ParallelProcessesEditFieldLabel.Text = 'Parallel Processes';
            app.ParallelProcessesEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.ParallelProcessesEditField.Limits = [1 Inf];
            app.ParallelProcessesEditField.Value = 1;
            app.VTAthresholdVmEditFieldLabel = uilabel(app.OptimizationTab);
            app.VTAthresholdVmEditFieldLabel.Text = 'VTA threshold [V/m]';
            app.VTAthresholdVmEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.VTAthresholdVmEditField.Limits = [0 Inf];
            app.VTAthresholdVmEditField.Value = 200;
            app.RebuildLabel = uilabel(app.OptimizationTab);
            app.RebuildLabel.Text = 'Rebuild';
            app.Rebuild = uicheckbox(app.OptimizationTab);
            app.Rebuild.Text = '';
            app.ConstraintthresholdVmEditFieldLabel = uilabel(app.OptimizationTab);
            app.ConstraintthresholdVmEditFieldLabel.Text = 'Constraint threshold [V/m]';
            app.ConstraintthresholdVmEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.ConstraintthresholdVmEditField.Limits = [0 Inf];
            app.ConstraintthresholdVmEditField.Value = 100;
            app.omega_tomega_comega_sLabel = uilabel(app.OptimizationTab);
            app.omega_tomega_comega_sLabel.Interpreter = 'latex';
            app.omega_tomega_comega_sLabel.Text = '$\omega_t,\omega_c,\omega_s$';
            app.RankingcoefficientsLabel = uilabel(app.OptimizationTab);
            app.RankingcoefficientsLabel.Text = 'Ranking coefficients';
            app.Omega = uieditfield(app.OptimizationTab, 'text');
            app.Omega.Value = '1,1,0';
            app.PlotResultsLabel = uilabel(app.OptimizationTab);
            app.PlotResultsLabel.Text = 'Plot Results';
            app.PlotOption = uicheckbox(app.OptimizationTab);
            app.PlotOption.Text = '';
            app.CompareSettingsTab = uitab(app.TabGroup);
            app.CompareSettingsTab.Title = 'Compare Settings';
            app.SelectPatientListBoxLabel = uilabel(app.CompareSettingsTab);
            app.SelectPatientListBoxLabel.Text = 'Select Patient';
            app.SelectPatientListBox = uilistbox(app.CompareSettingsTab);
            app.SelectPatientListBox.Items = {};
            app.SelectPatientListBox.ValueChangedFcn = createCallbackFcn(app, @SelectPatientListBoxValueChanged, true);
            app.SelectPatientListBox.Value = {};
            app.DiceCheckBox = uicheckbox(app.CompareSettingsTab);
            app.DiceCheckBox.Text = '';
            app.DiceCoefficientsLabel = uilabel(app.CompareSettingsTab);
            app.DiceCoefficientsLabel.Text = 'Dice Coefficients';
            app.TargetCoverageLabel = uilabel(app.CompareSettingsTab);
            app.TargetCoverageLabel.Text = 'Target Coverage';
            app.TargetCovCheckBox = uicheckbox(app.CompareSettingsTab);
            app.TargetCovCheckBox.Text = '';
            app.CompareSettingsLabel = uilabel(app.CompareSettingsTab);
            app.CompareSettingsLabel.Text = 'Compare Settings';
            app.CheckBoxCompareSettings = uicheckbox(app.CompareSettingsTab);
            app.CheckBoxCompareSettings.Text = '';
            app.UITableSettingsLeft = uitable(app.CompareSettingsTab);
            app.UITableSettingsLeft.ColumnName = {'Active Contacts'; 'Amplitude [mA]'; 'PW [us]'};
            app.UITableSettingsLeft.RowName = {};
            app.UITableSettingsLeft.ColumnEditable = true;
            app.UITableSettingsLeft.CellEditCallback = createCallbackFcn(app, @UITableSettingsLeftCellEdit, true);
            app.LeftHemisphereLabel = uilabel(app.CompareSettingsTab);
            app.LeftHemisphereLabel.Text = 'Left Hemisphere';
            app.UITableSettingsRight = uitable(app.CompareSettingsTab);
            app.UITableSettingsRight.ColumnName = {'Active Contacts'; 'Amplitude [mA]'; 'PW [us]'};
            app.UITableSettingsRight.RowName = {};
            app.UITableSettingsRight.ColumnEditable = true;
            app.UITableSettingsRight.CellEditCallback = createCallbackFcn(app, @UITableSettingsRightCellEdit, true);
            app.RightHemisphereLabel = uilabel(app.CompareSettingsTab);
            app.RightHemisphereLabel.Text = 'Right Hemisphere';
            app.InsertRowButtonLeft = uibutton(app.CompareSettingsTab, 'push');
            app.InsertRowButtonLeft.ButtonPushedFcn = createCallbackFcn(app, @InsertRowButtonLeftPushed, true);
            app.InsertRowButtonLeft.Icon = fullfile(pathToMLAPP, 'AppIcons', 'addButton.png');
            app.InsertRowButtonLeft.Text = '';
            app.InsertRowButtonRight = uibutton(app.CompareSettingsTab, 'push');
            app.InsertRowButtonRight.ButtonPushedFcn = createCallbackFcn(app, @InsertRowButtonRightPushed, true);
            app.InsertRowButtonRight.Icon = fullfile(pathToMLAPP, 'AppIcons', 'addButton.png');
            app.InsertRowButtonRight.Text = '';
            app.DeleteRowButtonLeft = uibutton(app.CompareSettingsTab, 'push');
            app.DeleteRowButtonLeft.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonLeftPushed, true);
            app.DeleteRowButtonLeft.Icon = fullfile(pathToMLAPP, 'AppIcons', 'subtractButton.png');
            app.DeleteRowButtonLeft.Text = '';
            app.DeleteRowButtonRight = uibutton(app.CompareSettingsTab, 'push');
            app.DeleteRowButtonRight.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonRightPushed, true);
            app.DeleteRowButtonRight.Icon = fullfile(pathToMLAPP, 'AppIcons', 'subtractButton.png');
            app.DeleteRowButtonRight.Text = '';
            app.RunButton = uibutton(app.TuneS, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = {'Run'; ''};
            app.TextArea = uitextarea(app.TuneS);
            % Show the figure after all components are created
            app.TuneS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TuneStim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TuneS)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TuneS)
        end
    end
end