classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptiStim                     matlab.ui.Figure
        ActivationCheckBox           matlab.ui.control.CheckBox
        OptimizeCheckBox             matlab.ui.control.CheckBox
        rebuild                      matlab.ui.control.CheckBox
        batchCheckBox                matlab.ui.control.CheckBox
        PlotCheckBox                 matlab.ui.control.CheckBox
        TextArea                     matlab.ui.control.TextArea
        RunButton                    matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        OptimizationTab              matlab.ui.container.Tab
        DistancefromtargettoclosestcontactCheckBox  matlab.ui.control.CheckBox
        OptimizationschemeDropDown   matlab.ui.control.DropDown
        OptimizationschemeDropDownLabel  matlab.ui.control.Label
        RelaxationTargetCoverageEditField  matlab.ui.control.NumericEditField
        SafetyMarginLabel            matlab.ui.control.Label
        ThresholdVmEditField         matlab.ui.control.NumericEditField
        ThresholdVmLabel             matlab.ui.control.Label
        ParallelProcessesEditField   matlab.ui.control.NumericEditField
        ParallelProcessesEditFieldLabel  matlab.ui.control.Label
        SpaceLabel                   matlab.ui.control.Label
        Space                        matlab.ui.control.Switch
        AtlasDropDown                matlab.ui.control.DropDown
        AtlasDropDownLabel           matlab.ui.control.Label
        TargetButton                 matlab.ui.control.Button
        TargetListBox                matlab.ui.control.ListBox
        ConstraintListBox            matlab.ui.control.ListBox
        ConstraintButton             matlab.ui.control.Button
        ActivationTab                matlab.ui.container.Tab
        AtlasDropDown_2              matlab.ui.control.DropDown
        AtlasDropDown_2Label         matlab.ui.control.Label
        SpaceLabel_2                 matlab.ui.control.Label
        Space_2                      matlab.ui.control.Switch
        TargetButton_2               matlab.ui.control.Button
        TargetListBox_2              matlab.ui.control.ListBox
        ConstraintListBox_2          matlab.ui.control.ListBox
        ConstraintButton_2           matlab.ui.control.Button
        DBSTuneSLabel                matlab.ui.control.Label
        RightCheck                   matlab.ui.control.CheckBox
        LeftCheck                    matlab.ui.control.CheckBox
        RightEditField               matlab.ui.control.NumericEditField
        OrientationLabel             matlab.ui.control.Label
        LeadDropDown                 matlab.ui.control.DropDown
        LeadDropDownLabel            matlab.ui.control.Label
        RightEditFieldLabel          matlab.ui.control.Label
        LeftEditField                matlab.ui.control.NumericEditField
        LeftEditFieldLabel           matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        BrowseButton                 matlab.ui.control.Button
        PatientFolderEditField       matlab.ui.control.EditField
        PatientFolderEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        gui = addpath('./GUI')
        mni = addpath('./MNI')
        leads = addpath('./Leads')
        comsol = addpath('./Comsol')
        %code = addpath('./OptiStim_code')
        
        selectedPath = 'C:' 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function PatientFolderEditFieldValueChanged(app, event)
            %app.PatientFolderEditField.Value = app.selectedPath;

        end

        % Callback function: BrowseButton, PatientFolderEditField
        function BrowseButtonPushed(app, event)
            app.selectedPath = uigetdir();
            app.PatientFolderEditField.Value = app.selectedPath;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
           
            patientfolder = app.selectedPath;
            lead = app.LeadDropDown.Value;
            right = app.RightCheck.Value;
            left = app.LeftCheck.Value;
            atlas = app.AtlasDropDown.Value;
            targets = app.TargetListBox.Value;
            constraints = app.ConstraintListBox.Value;
            rebui = app.rebuild.Value;
            plotoption = app.PlotCheckBox.Value;

            batch = app.batchCheckBox.Value;
            space = app.Space.Value;
            threads = app.ParallelProcessesEditField.Value;
            optischeme = app.OptimizationschemeDropDown.Value;
            EThreshold = app.ThresholdVmEditField.Value;
            SafetyMargin = app.RelaxationTargetCoverageEditField.Value;

            if left == 1 && right == 0
                hemisphere  = {'sin'};
                lead_orientation = [app.LeftEditField.Value];
            elseif right == 1 && left == 0
                hemisphere = {'dx'};
                lead_orientation = [app.RightEditField.Value];
            elseif right==1 && left==1
                hemisphere = {"dx","sin"};
                lead_orientation = [app.LeftEditField.Value,app.RightEditField.Value];
            end

            if strcmp(optischeme,'Conservative')
                optischeme = 'conservative';
            elseif strcmp(optischeme,'Minimum coverage')
                optischeme = 'mincov';
            end

            
            if strcmp(patientfolder,'C:')
                app.TextArea.Value = 'Please pick patient directory';

            elseif ~exist('hemisphere','var')==1
                app.TextArea.Value = 'Please pick hemisphere and insert lead orientation';
             
            elseif batch
                patientfolder = strcat("'", patientfolder,"'");
                atlas = strcat("'", atlas,"'");
                space = strcat("'", space,"'");
                lead = strcat("'",lead,"'");
                optischeme = strcat("'",optischeme,"'");

                hemisphere = cell_to_string(hemisphere);
                lead_orientation = append('[',cell_to_string(lead_orientation),']');
                targets = cell_to_string(targets);
                constraints = cell_to_string(constraints);
                
                cmd = sprintf('sbatch Pipeline.sh %s %s %s %s %s %s %s %s %s %s %s %s',patientfolder,hemisphere,lead,lead_orientation,atlas,targets,constraints,optischeme,string(EThreshold),string(SafetyMargin),string(threads),space,string(rebui));
                app.TextArea.Value = cmd;
                pause(1)
                [nr,msg]=system(cmd);
                app.TextArea.Value = msg;
                
            else
                set(app.OptiStim, 'Pointer','watch')
                set(app.RunButton,'Text',{'Running...';''})
                set(app.TextArea,'Value', '')
                pause(0.1)
                msg = main(patientfolder,hemisphere,lead,lead_orientation,atlas,targets,constraints,optischeme,EThreshold,SafetyMargin,threads,space,plotoption,rebui);
                app.TextArea.Value = msg;
                app.OptiStim.Pointer = 'arrow';
                app.RunButton.Text = {'Run'; ''};
            end
    
            function str = cell_to_string(cellstring)
            str = '';
            for i = 1:length(cellstring)
                try
                    content = cellstring{i};
                catch
                    content = string(cellstring(i));
                end
                str = append(str,content,',');
            end
            str = strcat("'",str,"'");
         end
        end

        % Button pushed function: ConstraintButton
        function ConstraintButtonPushed(app, event)
            if app.ConstraintListBox.Visible == 1
              set(app.ConstraintListBox,'Visible',0);
            else
              set(app.ConstraintListBox,'Visible',1);
            end
            
        end

        % Button pushed function: TargetButton
        function TargetButtonPushed(app, event)
            if app.TargetListBox.Visible == 1
                set(app.TargetListBox,'Visible',0);
            else
                set(app.TargetListBox,'Visible',1);
            end
        end

        % Value changed function: AtlasDropDown
        function AtlasDropDownValueChanged(app, event)
            value = app.AtlasDropDown.Value;
            
            if strcmp(value, 'DISTAL Minimal (Ewert 2017)')
                items = {'STN motor','STN associative','STN limbic','GPi premotor','GPi primarymotor','GPi sensorimotor','GPi sensory','GPi temporal'};
                itemsdata = {'STN_motor.nii.gz','STN_associative.nii.gz','STN_limbic.nii.gz','GPi_premotor.nii.gz','GPi_primarymotor.nii.gz','GPi_sensorimotor.nii.gz','GPi_sensory.nii.gz','GPi_temporal.nii.gz'};
                
            elseif strcmp(value,'STN Sweetspots (Dembek 2019)')
                items = {'Any Side Effect MeanMapGradient','Overall Motor Improvement MeanMapGradient'};
                itemsdata = {'Any_Side_Effect_MeanMapGradient.nii.gz','Overall_Motor_Improvement_MeanMapGradient.nii.gz'};

            elseif strcmp(value,'STN-Subdivisions (Accolla 2014)')
                items = {'STN motor','STN associative','STN limbic'};
                itemsdata = {'STN_motor.nii','STN_associative.nii','STN_limbic.nii'};

            elseif strcmp(value,'DBS Tractography Atlas (Middlebrooks 2020)')
                items= {'STN limbic', 'STN limbic tract', 'STN associative','STN associative tract','STN motor','STN motor tract'};
                itemsdata = {'STN_limbic.nii.gz', 'STN_limbic_tract.mat', 'STN_associative.nii.gz','STN_associative_tract.mat','STN_motor.nii.gz','STN_motor_tract.mat'};
                
            elseif strcmp(value,'Human Dysfunctome Atlas (Hollunder 2024)')
                items= {'PD Sweet Spot', 'DYT Sweet Spot', 'OCD Sweet Spot','TS Sweet Spot','None'};
                itemsdata = {'Sweet_Spot_PD.nii.gz', 'Sweet_Spot_DYT.nii.gz', 'Sweet_Spot_OCD.nii.gz','Sweet_Spot_TS.nii.gz',''};
            end


            set(app.ConstraintListBox,'Items',items);
            set(app.TargetListBox,'Items',items);

            set(app.ConstraintListBox,'ItemsData',itemsdata);
            set(app.TargetListBox,'ItemsData',itemsdata);
        end

        % Value changed function: LeftCheck, LeftEditField, RightCheck
        function HemisphereCheckValueChanged(app, event)
            valueR = app.RightCheck.Value;
           
            if valueR==1
                set(app.RightEditField,'Visible',1)
            else
                set(app.RightEditField,'Visible',0)
            end
             
            valueL = app.LeftCheck.Value;
           
            if valueL==1
                set(app.LeftEditField,'Visible',1)
            else
                set(app.LeftEditField,'Visible',0)
            end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptiStim = uifigure('Visible', 'off');
            app.OptiStim.Name = 'TuneS';
            app.PatientFolderEditFieldLabel = uilabel(app.OptiStim);
            app.PatientFolderEditFieldLabel.Text = {'Patient Folder'; ''};
            app.PatientFolderEditField = uieditfield(app.OptiStim, 'text');
            app.PatientFolderEditField.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.PatientFolderEditField.Editable = 'off';
            app.BrowseButton = uibutton(app.OptiStim, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Image = uiimage(app.OptiStim);
            app.Image.ImageSource = 'option1.png';
            app.LeftEditFieldLabel = uilabel(app.OptiStim);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftEditField = uieditfield(app.OptiStim, 'numeric');
            app.LeftEditField.Limits = [0 360];
            app.LeftEditField.ValueChangedFcn = createCallbackFcn(app, @HemisphereCheckValueChanged, true);
            app.LeftEditField.Visible = 'off';
            app.RightEditFieldLabel = uilabel(app.OptiStim);
            app.RightEditFieldLabel.Text = 'Right';
            app.LeadDropDownLabel = uilabel(app.OptiStim);
            app.LeadDropDownLabel.Text = 'Lead';
            app.LeadDropDown = uidropdown(app.OptiStim);
            app.LeadDropDown.Items = {'S:t Jude 1331', 'Boston Scientific 2202', 'Boston Scientific Vercise Cartesia'};
            app.LeadDropDown.Value = 'S:t Jude 1331';
            app.OrientationLabel = uilabel(app.OptiStim);
            app.OrientationLabel.Text = 'Orientation';
            app.RightEditField = uieditfield(app.OptiStim, 'numeric');
            app.RightEditField.Limits = [0 360];
            app.RightEditField.Visible = 'off';
            app.LeftCheck = uicheckbox(app.OptiStim);
            app.LeftCheck.ValueChangedFcn = createCallbackFcn(app, @HemisphereCheckValueChanged, true);
            app.LeftCheck.Text = '';
            app.RightCheck = uicheckbox(app.OptiStim);
            app.RightCheck.ValueChangedFcn = createCallbackFcn(app, @HemisphereCheckValueChanged, true);
            app.RightCheck.Text = '';
            app.DBSTuneSLabel = uilabel(app.OptiStim);
            app.DBSTuneSLabel.Text = {'DBS TuneS'; ''};
            app.TabGroup = uitabgroup(app.OptiStim);
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.ConstraintButton = uibutton(app.OptimizationTab, 'push');
            app.ConstraintButton.ButtonPushedFcn = createCallbackFcn(app, @ConstraintButtonPushed, true);
            app.ConstraintButton.Text = 'Constraint';
            app.ConstraintListBox = uilistbox(app.OptimizationTab);
            app.ConstraintListBox.Items = {'STN associative', 'STN motor', 'STN limbic', 'GPi premotor', 'GPi primarymotor', 'GPi sensorimotor', 'GPi sensory', 'GPi temporal'};
            app.ConstraintListBox.ItemsData = {'STN_associative.nii.gz', 'STN_motor.nii.gz', 'STN_limbic.nii.gz', 'GPi_premotor.nii.gz', 'GPi_primarymotor.nii.gz', 'GPi_sensorimotor.nii.gz', 'GPi_sensory.nii.gz', 'GPi_temporal.nii.gz'};
            app.ConstraintListBox.Multiselect = 'on';
            app.ConstraintListBox.Visible = 'off';
            app.ConstraintListBox.Value = {'STN_associative.nii.gz'};
            app.TargetListBox = uilistbox(app.OptimizationTab);
            app.TargetListBox.Items = {'STN associative', 'STN motor', 'STN limbic', 'GPi premotor', 'GPi primarymotor', 'GPi sensorimotor', 'GPi sensory', 'GPi temporal'};
            app.TargetListBox.ItemsData = {'STN_associative.nii.gz', 'STN_motor.nii.gz', 'STN_limbic.nii.gz', 'GPi_premotor.nii.gz', 'GPi_primarymotor.nii.gz', 'GPi_sensorimotor.nii.gz', 'GPi_sensory.nii.gz', 'GPi_temporal.nii.gz'};
            app.TargetListBox.Multiselect = 'on';
            app.TargetListBox.Visible = 'off';
            app.TargetListBox.Value = {'STN_associative.nii.gz'};
            app.TargetButton = uibutton(app.OptimizationTab, 'push');
            app.TargetButton.ButtonPushedFcn = createCallbackFcn(app, @TargetButtonPushed, true);
            app.TargetButton.Text = 'Target';
            app.AtlasDropDownLabel = uilabel(app.OptimizationTab);
            app.AtlasDropDownLabel.Text = 'Atlas';
            app.AtlasDropDown = uidropdown(app.OptimizationTab);
            app.AtlasDropDown.Items = {'DISTAL Minimal (Ewert 2017)', 'STN Sweetspots (Dembek 2019)', 'STN-Subdivisions (Accolla 2014)', 'DBS Tractography Atlas (Middlebrooks 2020)', 'Human Dysfunctome Atlas (Hollunder 2024)'};
            app.AtlasDropDown.ValueChangedFcn = createCallbackFcn(app, @AtlasDropDownValueChanged, true);
            app.AtlasDropDown.Value = 'DISTAL Minimal (Ewert 2017)';
            app.Space = uiswitch(app.OptimizationTab, 'slider');
            app.Space.Items = {'native', 'MNI'};
            app.Space.Value = 'native';
            app.SpaceLabel = uilabel(app.OptimizationTab);
            app.SpaceLabel.Text = 'Space';
            app.ParallelProcessesEditFieldLabel = uilabel(app.OptimizationTab);
            app.ParallelProcessesEditFieldLabel.Text = 'Parallel Processes';
            app.ParallelProcessesEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.ParallelProcessesEditField.Limits = [1 20];
            app.ParallelProcessesEditField.Value = 1;
            app.ThresholdVmLabel = uilabel(app.OptimizationTab);
            app.ThresholdVmLabel.Text = {'Threshold  (V/m)'; ''};
            app.ThresholdVmEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.ThresholdVmEditField.Value = 200;
            app.SafetyMarginLabel = uilabel(app.OptimizationTab);
            app.SafetyMarginLabel.Text = 'Relaxation/Target Coverage (%)';
            app.RelaxationTargetCoverageEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.RelaxationTargetCoverageEditField.Value = 10;
            app.OptimizationschemeDropDownLabel = uilabel(app.OptimizationTab);
            app.OptimizationschemeDropDownLabel.Text = 'Optimization scheme';
            app.OptimizationschemeDropDown = uidropdown(app.OptimizationTab);
            app.OptimizationschemeDropDown.Items = {'Conservative', 'Minimum coverage'};
            app.OptimizationschemeDropDown.Value = 'Conservative';
            app.DistancefromtargettoclosestcontactCheckBox = uicheckbox(app.OptimizationTab);
            app.DistancefromtargettoclosestcontactCheckBox.Text = '    Distance from target to closest contact';
            app.ActivationTab = uitab(app.TabGroup);
            app.ActivationTab.Title = 'Activation';
            app.ConstraintButton_2 = uibutton(app.ActivationTab, 'push');
            app.ConstraintButton_2.Text = 'Constraint';
            app.ConstraintListBox_2 = uilistbox(app.ActivationTab);
            app.ConstraintListBox_2.Items = {'STN associative', 'STN motor', 'STN limbic', 'GPi premotor', 'GPi primarymotor', 'GPi sensorimotor', 'GPi sensory', 'GPi temporal'};
            app.ConstraintListBox_2.ItemsData = {'STN_associative.nii.gz', 'STN_motor.nii.gz', 'STN_limbic.nii.gz', 'GPi_premotor.nii.gz', 'GPi_primarymotor.nii.gz', 'GPi_sensorimotor.nii.gz', 'GPi_sensory.nii.gz', 'GPi_temporal.nii.gz'};
            app.ConstraintListBox_2.Multiselect = 'on';
            app.ConstraintListBox_2.Visible = 'off';
            app.ConstraintListBox_2.Value = {'STN_associative.nii.gz'};
            app.TargetListBox_2 = uilistbox(app.ActivationTab);
            app.TargetListBox_2.Items = {'STN associative', 'STN motor', 'STN limbic', 'GPi premotor', 'GPi primarymotor', 'GPi sensorimotor', 'GPi sensory', 'GPi temporal'};
            app.TargetListBox_2.ItemsData = {'STN_associative.nii.gz', 'STN_motor.nii.gz', 'STN_limbic.nii.gz', 'GPi_premotor.nii.gz', 'GPi_primarymotor.nii.gz', 'GPi_sensorimotor.nii.gz', 'GPi_sensory.nii.gz', 'GPi_temporal.nii.gz'};
            app.TargetListBox_2.Multiselect = 'on';
            app.TargetListBox_2.Visible = 'off';
            app.TargetListBox_2.Value = {'STN_associative.nii.gz'};
            app.TargetButton_2 = uibutton(app.ActivationTab, 'push');
            app.TargetButton_2.Text = 'Target';
            app.Space_2 = uiswitch(app.ActivationTab, 'slider');
            app.Space_2.Items = {'native', 'MNI'};
            app.Space_2.Value = 'native';
            app.SpaceLabel_2 = uilabel(app.ActivationTab);
            app.SpaceLabel_2.Text = 'Space';
            app.AtlasDropDown_2Label = uilabel(app.ActivationTab);
            app.AtlasDropDown_2Label.Text = 'Atlas';
            app.AtlasDropDown_2 = uidropdown(app.ActivationTab);
            app.AtlasDropDown_2.Items = {'DISTAL Minimal (Ewert 2017)', 'STN Sweetspots (Dembek 2019)', 'STN-Subdivisions (Accolla 2014)', 'DBS Tractography Atlas (Middlebrooks 2020)', 'Human Dysfunctome Atlas (Hollunder 2024)'};
            app.AtlasDropDown_2.Value = 'DISTAL Minimal (Ewert 2017)';
            app.RunButton = uibutton(app.OptiStim, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = {'Run'; ''};
            app.TextArea = uitextarea(app.OptiStim);
            app.PlotCheckBox = uicheckbox(app.OptiStim);
            app.PlotCheckBox.Text = '    Plot';
            app.batchCheckBox = uicheckbox(app.OptiStim);
            app.batchCheckBox.Text = '    Submit batch job';
            app.rebuild = uicheckbox(app.OptiStim);
            app.rebuild.Text = '    Rebuild';
            app.OptimizeCheckBox = uicheckbox(app.OptiStim);
            app.OptimizeCheckBox.Text = '    Optimize';
            app.ActivationCheckBox = uicheckbox(app.OptiStim);
            app.ActivationCheckBox.Text = '    Activation';
            % Show the figure after all components are created
            app.OptiStim.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptiStim)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptiStim)
        end
    end
end