classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        PatientsTab               matlab.ui.container.Tab
        ConfirmselectionButton    matlab.ui.control.Button
        UITableSubfolders         matlab.ui.control.Table
        LoadSubfoldersButton      matlab.ui.control.Button
        UITablePatients           matlab.ui.control.Table
        OrientationLabel          matlab.ui.control.Label
        UITableLeads              matlab.ui.control.Table
        UITableOrientation        matlab.ui.control.Table
        OptimizationTab           matlab.ui.container.Tab
        ActivationTab             matlab.ui.container.Tab
        BrowseButton              matlab.ui.control.Button
        CohortPathEditField       matlab.ui.control.EditField
        CohortPathEditFieldLabel  matlab.ui.control.Label
        DBSTuneSLabel             matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end

    
    properties (Access = private)
        gui = addpath('./GUI')
        mni = addpath('./MNI')
        leads = addpath('./Leads')
        comsol = addpath('./Comsol')
        selectedPath = 'C:' 
        selectedPatients = []
        selectedOrientation = [];
         % Description
    end
    
    methods (Access = private)
        
        function initTables(app)
            Npatients = length(app.selectedPatients);
            app.UITableLeads.Data = repmat({'S:t Jude 1331'},Npatients,1); 
            %app.UITableLeads.ColumnFormat = {{'S:t Jude 1331', 'Boston Scientific 2202', 'Boston Scientific Vercise Cartesia'}}; % have option cell array within ColumnFormat property to indicate the 1st column is using dropdown menus.
            app.UITableLeads.ColumnEditable = true;

            app.UITableOrientation.ColumnEditable = true;
            app.UITableOrientation.Data = repmat({0 0},Npatients,1); 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITableLeads.Data = {'S:t Jude 1331'; 'Boston Scientific 2202'; 'Boston Scientific Vercise Cartesia'};%repmat({'S:t Jude 1331'},Npatients,1); 
            app.UITableLeads.ColumnFormat = {{'S:t Jude 1331', 'Boston Scientific 2202', 'Boston Scientific Vercise Cartesia'}}; % have option cell array within ColumnFormat property to indicate the 1st column is using dropdown menus.
            app.UITableLeads.ColumnEditable = true;
        end

        % Callback function: BrowseButton, CohortPathEditField
        function BrowseButtonPushed(app, event)
            app.selectedPath = uigetdir();
            app.CohortPathEditField.Value = app.selectedPath;
        end

        % Button pushed function: LoadSubfoldersButton
        function LoadSubfoldersButtonPushed(app, event)
            % Open folder selection dialog
            folderPath = app.selectedPath;
            if folderPath == 0
                return; % User cancelled the folder selection
            end
            
            % Get list of sub-folders
            subFolders = dir(folderPath);
            subFolderNames = {subFolders([subFolders.isdir] & ~ismember({subFolders.name}, {'.', '..'})).name};
            
            % Create data for the table
            tableData = cell(length(subFolderNames), 1);
            for i = 1:length(subFolderNames)
                tableData{i, 1} = subFolderNames{i};
            end
            
            % Set table data
            app.UITableSubfolders.Data = tableData;
        end

        % Button pushed function: ConfirmselectionButton
        function ConfirmselectionButtonPushed(app, event)
            allSubfolders = app.UITableSubfolders.Data;
            selectedSubfolders = app.selectedPatients;
            
            % Create a table with the selected subfolder names
            data = cell(length(selectedSubfolders), 1);
            for i = 1:length(selectedSubfolders)
                idx = selectedSubfolders(i);
                data{i, 1} = allSubfolders{idx};
            end

            % Update the table in the app
            app.UITablePatients.Data = data;

            % Fill table for lead selection
            initTables(app)
        end

        % Cell selection callback: UITableSubfolders
        function UITableSubfoldersCellSelection(app, event)
            app.selectedPatients = event.Indices;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'option1.png';
            app.DBSTuneSLabel = uilabel(app.UIFigure);
            app.DBSTuneSLabel.Text = {'DBS TuneS'; ''};
            app.CohortPathEditFieldLabel = uilabel(app.UIFigure);
            app.CohortPathEditFieldLabel.Text = 'Cohort Path';
            app.CohortPathEditField = uieditfield(app.UIFigure, 'text');
            app.CohortPathEditField.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PatientsTab = uitab(app.TabGroup);
            app.PatientsTab.Title = 'Patients';
            app.UITableOrientation = uitable(app.PatientsTab);
            app.UITableOrientation.ColumnName = {'Left'; 'Right'};
            app.UITableOrientation.RowName = {};
            app.UITableLeads = uitable(app.PatientsTab);
            app.UITableLeads.ColumnName = {'Lead Type'};
            app.UITableLeads.RowName = {};
            app.UITableLeads.ColumnEditable = true;
            app.OrientationLabel = uilabel(app.PatientsTab);
            app.OrientationLabel.Text = 'Orientation';
            app.UITablePatients = uitable(app.PatientsTab);
            app.UITablePatients.ColumnName = {'Included Patients'};
            app.UITablePatients.RowName = {};
            app.LoadSubfoldersButton = uibutton(app.PatientsTab, 'push');
            app.LoadSubfoldersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSubfoldersButtonPushed, true);
            app.LoadSubfoldersButton.Text = 'Load Subfolders';
            app.UITableSubfolders = uitable(app.PatientsTab);
            app.UITableSubfolders.ColumnName = {'Subfolders'};
            app.UITableSubfolders.RowName = {};
            app.UITableSubfolders.CellSelectionCallback = createCallbackFcn(app, @UITableSubfoldersCellSelection, true);
            app.ConfirmselectionButton = uibutton(app.PatientsTab, 'push');
            app.ConfirmselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmselectionButtonPushed, true);
            app.ConfirmselectionButton.Text = 'Confirm selection';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.ActivationTab = uitab(app.TabGroup);
            app.ActivationTab.Title = 'Activation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end